classdef Pump_Monitorv2_4gauges < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        INSTRUMENTALPANELPanel      matlab.ui.container.Panel
        HydraulicPowerkWGauge       matlab.ui.control.Gauge
        HydraulicPowerkWGaugeLabel  matlab.ui.control.Label
        HeadmGauge                  matlab.ui.control.Gauge
        HeadmGaugeLabel             matlab.ui.control.Label
        FlowRateLsGauge             matlab.ui.control.Gauge
        FlowRateLsGaugeLabel        matlab.ui.control.Label
        PressurekPaGauge            matlab.ui.control.Gauge
        PressurekPaGaugeLabel       matlab.ui.control.Label
        PLOTPANELPanel              matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        ResultDropDown              matlab.ui.control.DropDown
        ResultDropDownLabel         matlab.ui.control.Label
        SelectInputDataButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInputDataButton
        function SelectInputDataButtonPushed(comp, event)
            [filename, pathname] = uigetfile('*.txt', 'Select a text file'); % open dialog box to select file
if isequal(filename,0)
   disp('User selected Cancel');
else
   disp(['User selected ', fullfile(pathname, filename)]);
end

fileID = fopen(fullfile(pathname, filename),'r'); % open the selected file in read mode


% Read the data using textscan, 
formatSpec = '%f V,%f counts,%f ms';             
plotGrid = 'on';                
 
delay = 0.2;                   
 
%Define Function Variables
time = 0;
ppressure=0;
fflow_rate=0;
hhead=0;
hhydraulic_power=0;
count = 0;
  %Read Data from Serial as Float
  data = textscan(fileID, formatSpec);
  % Access the data
  voltage = data{1};
  count_number = data{2};
  time_interval = data{3};
  endn=length(voltage);
  v_s=5.0;
  count_reference=330;
  density_water=997;
  gravity=9.81;
  pressure=1/0.0018*(voltage./v_s-0.04);
  head=pressure./density_water./gravity*1000;
  flow_rate=count_number/count_reference*1000./time_interval;
  hydraulic_power=pressure.*flow_rate;
  tic 
  value = comp.ResultDropDown.Value;
  while count <endn
            if(~isempty(pressure) && isfloat(pressure)) %Make sure Data Type is Correct        
              count = count + 1;   
              time(count) = toc;   %stop the stopwatch and extract time
              ttime(count)=time_interval(1)*count/1000;
              ppressure(count)=pressure(count); %Extract 1st Data Element
              fflow_rate(count) = flow_rate(count); %Extract 1st Data Element  
              hhead(count) = head(count); %Extract 1st Data Element 
              hhydraulic_power(count) = hydraulic_power(count); %Extract 1st Data Element     
              comp.PressurekPaGauge.Value=ppressure(count);
              comp.FlowRateLsGauge.Value=fflow_rate(count);
              comp.HeadmGauge.Value=hhead(count);
              comp.HydraulicPowerkWGauge.Value=hhydraulic_power(count);
switch value
     case 'Pressure vs. Time'
        ax = comp.UIAxes;
        plotGraph = plot(ax, time, ppressure,'-r');
        title(ax, 'Pressure vs.Time','FontSize',18);
        xlabel(ax, 'Time, s','FontSize',15);
        ylabel(ax, 'Pressure, kPa','FontSize',15);
        grid(ax, plotGrid);
        set(plotGraph,'XData',ttime,'YData',ppressure);
    case 'Flow rate vs. Time'
         %Set up Plot
            ax = comp.UIAxes;
            plotGraph = plot(ax, time, fflow_rate,'-r');
            title(ax, 'Flow rate vs.Time','FontSize',18);
            xlabel(ax, 'Time, s','FontSize',15);
            ylabel(ax, 'Flow rate, L/s','FontSize',15);
            grid(ax, plotGrid);
            set(plotGraph,'XData',ttime,'YData',fflow_rate);  
    case 'Head vs. Flow rate'
         %Set up Plot
            ax = comp.UIAxes;
            plotGraph = plot(ax, fflow_rate, hhead,'-r');
            title(ax, 'Head vs.Flow rate','FontSize',18);
            xlabel(ax, 'Flow rate, L/s','FontSize',15);
            ylabel(ax, 'Head, m','FontSize',15);
            grid(ax, plotGrid);
            set(plotGraph,'XData',fflow_rate,'YData',hhead); 
    case 'Hydraulic power vs. Flow rate'
         %Set up Plot
        ax = comp.UIAxes;
        plotGraph = plot(ax, fflow_rate, hhydraulic_power,'-r');
        title(ax, 'Hydraulic power vs.Flow rate','FontSize',18);
        xlabel(ax, 'Flow rate, L/s','FontSize',15);
        ylabel(ax, 'Hydraulic power, kW','FontSize',15);
        grid(ax, plotGrid);
        set(plotGraph,'XData',fflow_rate,'YData',hhydraulic_power);
      end
            pause(delay);
            end
  end
      
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1549 762];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SelectInputDataButton = uibutton(comp, 'push');
            comp.SelectInputDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectInputDataButtonPushed, true);
            comp.SelectInputDataButton.Text = 'Select Input Data';
            comp.ResultDropDownLabel = uilabel(comp);
            comp.ResultDropDownLabel.Text = 'Result';
            comp.ResultDropDown = uidropdown(comp);
            comp.ResultDropDown.Items = {'Pressure vs. Time', 'Flow rate vs. Time', 'Head vs. Flow rate', 'Hydraulic power vs. Flow rate'};
            comp.ResultDropDown.Value = 'Pressure vs. Time';
            comp.PLOTPANELPanel = uipanel(comp);
            comp.PLOTPANELPanel.Title = 'PLOT PANEL';
            comp.UIAxes = uiaxes(comp.PLOTPANELPanel);
            title(comp.UIAxes, 'Title')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.INSTRUMENTALPANELPanel = uipanel(comp);
            comp.INSTRUMENTALPANELPanel.Title = 'INSTRUMENTAL PANEL';
            comp.PressurekPaGaugeLabel = uilabel(comp.INSTRUMENTALPANELPanel);
            comp.PressurekPaGaugeLabel.Text = 'Pressure, kPa';
            comp.PressurekPaGauge = uigauge(comp.INSTRUMENTALPANELPanel, 'circular');
            comp.PressurekPaGauge.Limits = [0 30];
            comp.FlowRateLsGaugeLabel = uilabel(comp.INSTRUMENTALPANELPanel);
            comp.FlowRateLsGaugeLabel.Text = 'Flow Rate, L/s';
            comp.FlowRateLsGauge = uigauge(comp.INSTRUMENTALPANELPanel, 'circular');
            comp.FlowRateLsGauge.Limits = [0 0.3];
            comp.HeadmGaugeLabel = uilabel(comp.INSTRUMENTALPANELPanel);
            comp.HeadmGaugeLabel.Text = 'Head, m';
            comp.HeadmGauge = uigauge(comp.INSTRUMENTALPANELPanel, 'circular');
            comp.HeadmGauge.Limits = [0 3];
            comp.HydraulicPowerkWGaugeLabel = uilabel(comp.INSTRUMENTALPANELPanel);
            comp.HydraulicPowerkWGaugeLabel.Text = 'Hydraulic Power, kW';
            comp.HydraulicPowerkWGauge = uigauge(comp.INSTRUMENTALPANELPanel, 'circular');
            comp.HydraulicPowerkWGauge.Limits = [0 3];
        end
    end
end