classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ResultDropDown       matlab.ui.control.DropDown
        ResultDropDownLabel  matlab.ui.control.Label
        InputDataButton      matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputDataButton
        function InputDataButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.txt', 'Select a text file'); % open dialog box to select file
if isequal(filename,0)
   disp('User selected Cancel');
else
   disp(['User selected ', fullfile(pathname, filename)]);
end

fileID = fopen(fullfile(pathname, filename),'r'); % open the selected file in read mode


% Read the data using textscan
formatSpec = '%f V,%f counts,%f ms';             
plotGrid = 'on';                
 
delay = 0.2;                   
 
%Define Function Variables
time = 0;
ppressure=0;
fflow_rate=0;
hhead=0;
hhydraulic_power=0;
count = 0;
value = app.ResultDropDown.Value;
 switch value
     case 'Pressure vs. Time'
        %Set up Plot
        ax = app.UIAxes;
        plotGraph = plot(ax, time, ppressure,'-r');
        title(ax, 'Pressure vs.Time','FontSize',18);
        xlabel(ax, 'Time, s','FontSize',15);
        ylabel(ax, 'Pressure, kPa','FontSize',15);
        grid(ax, plotGrid);
                
            %Loop when Plot is Active
                       
             %Read Data from Serial as Float
               data = textscan(fileID, formatSpec);
                % Access the data
               voltage = data{1};
               time_interval=data{3};
               endn=length(voltage);
               v_s=5.0;
               pressure=1/0.0018*(voltage./v_s-0.04);
               tic 
        
           while count <endn
            if(~isempty(pressure) && isfloat(pressure)) %Make sure Data Type is Correct        
               count = count + 1;    
              time(count) = toc;   %stop the stopwatch and extract time
              ttime(count)=time_interval(1)*count/1000;
              ppressure(count)=pressure(count); %Extract 1st Data Element
              set(plotGraph,'XData',ttime,'YData',ppressure);         
             end
         
        %Allow MATLAB to Update Plot
        pause(delay);
        end
    
     case 'Flow rate vs. Time'
         %Set up Plot
            ax = app.UIAxes;
            plotGraph = plot(ax, time, fflow_rate,'-r');
            title(ax, 'Flow rate vs.Time','FontSize',18);
            xlabel(ax, 'Time, s','FontSize',15);
            ylabel(ax, 'Flow rate, L/s','FontSize',15);
            
            grid(ax, plotGrid);
         
 
%Loop when Plot is Active

    
    %Read Data from Serial as Float
    data = textscan(fileID, formatSpec);
% Access the data
        voltage = data{1};
        count_number = data{2};
        time_interval = data{3};
        endn=length(voltage);
        v_s=5.0;
        count_reference=330;
        density_water=997;
        gravity=9.81;
        pressure=1/0.0018*(voltage./v_s-0.04);
        flow_rate=count_number/count_reference*1000./time_interval;
        tic 
   while count <endn
    if(~isempty(flow_rate) && isfloat(flow_rate)) %Make sure Data Type is Correct        
        count = count + 1;    
        time(count) = toc;    %stop the stopwatch and extract time
        ttime(count)=time_interval(1)*count/1000;
        fflow_rate(count) = flow_rate(count); %Extract 1st Data Element        
        set(plotGraph,'XData',ttime,'YData',fflow_rate);     
    end
         
        %Allow MATLAB to Update Plot
        pause(delay);
end
     case 'Head vs. Flow rate'
         %Set up Plot
            ax = app.UIAxes;
            plotGraph = plot(ax, fflow_rate, hhead,'-r');
            title(ax, 'Head vs.Flow rate','FontSize',18);
            xlabel(ax, 'Flow rate, L/s','FontSize',15);
            ylabel(ax, 'Head, m','FontSize',15);
            grid(ax, plotGrid);
            
    
              %Read Data from Serial as Float
                data = textscan(fileID, formatSpec);
                % Access the data
                voltage = data{1};
                count_number = data{2};
                time_interval = data{3};
                endn=length(voltage);
                v_s=5.0;
                count_reference=330;
                density_water=997;
                gravity=9.81;
                pressure=1/0.0018*(voltage./v_s-0.04);
                head=pressure./density_water./gravity*1000;
                flow_rate=count_number/count_reference*1000./time_interval;
                
                tic 
                 %Loop when Plot is Active
         while count <endn
  
             if(~isempty(head) && isfloat(head)) %Make sure Data Type is Correct        
                     count = count + 1;    
                     fflow_rate(count) = flow_rate(count);    %stop the stopwatch and extract time
                     hhead(count) = head(count); %Extract 1st Data Element        
                     set(plotGraph,'XData',fflow_rate,'YData',hhead); 
                end
         
        %Allow MATLAB to Update Plot
        pause(delay);
        end
     case 'Hydraulic power vs. Flow rate'
         %Set up Plot
        ax = app.UIAxes;
        plotGraph = plot(ax, fflow_rate, hhydraulic_power,'-r');
        title(ax, 'Hydraulic power vs.Flow rate','FontSize',18);
        xlabel(ax, 'Flow rate, L/s','FontSize',15);
        ylabel(ax, 'Hydraulic power, kW','FontSize',15);
        grid(ax, plotGrid);
        
    
          %Read Data from Serial as Float
         data = textscan(fileID, formatSpec);
        % Access the data
        voltage = data{1};
        count_number = data{2};
        time_interval = data{3};
        endn=length(voltage);
        v_s=5.0;
        count_reference=330;
        density_water=997;
        gravity=9.81;
        pressure=1/0.0018*(voltage./v_s-0.04);
        head=pressure./density_water./gravity*1000;
        flow_rate=count_number/count_reference*1000./time_interval;
        hydraulic_power=pressure.*flow_rate;
        tic 
 
        %Loop when Plot is Active
        while count <endn
          if(~isempty(hydraulic_power) && isfloat(hydraulic_power)) %Make sure Data Type is Correct        
            count = count + 1;    
            fflow_rate(count) = flow_rate(count);    %stop the stopwatch and extract time
            hhydraulic_power(count) = hydraulic_power(count); %Extract 1st Data Element        
            set(plotGraph,'XData',fflow_rate,'YData',hhydraulic_power);
       
        end
         
        %Allow MATLAB to Update Plot
        pause(delay);
end

end


        end

        % Value changed function: ResultDropDown
        function ResultDropDownValueChanged(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {360, 360};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputDataButton = uibutton(app.LeftPanel, 'push');
            app.InputDataButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataButtonPushed, true);
            app.InputDataButton.Text = 'Input Data';
            app.ResultDropDownLabel = uilabel(app.LeftPanel);
            app.ResultDropDownLabel.Text = 'Result';
            app.ResultDropDown = uidropdown(app.LeftPanel);
            app.ResultDropDown.Items = {'Pressure vs. Time', 'Flow rate vs. Time', 'Head vs. Flow rate', 'Hydraulic power vs. Flow rate'};
            app.ResultDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultDropDownValueChanged, true);
            app.ResultDropDown.Value = 'Pressure vs. Time';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end