classdef new_Pump_Monitorv2_4gauges_live < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GaugePanel                  matlab.ui.container.Panel
        HydraulicPowerkWGauge       matlab.ui.control.Gauge
        HydraulicPowerkWGaugeLabel  matlab.ui.control.Label
        HeadmGauge                  matlab.ui.control.Gauge
        HeadmGaugeLabel             matlab.ui.control.Label
        FlowRateLsGauge             matlab.ui.control.Gauge
        FlowRateLsGaugeLabel        matlab.ui.control.Label
        PressurekPaGauge            matlab.ui.control.Gauge
        PressurekPaGaugeLabel       matlab.ui.control.Label
        PlotPanel                   matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        SelectLiveDataPanel         matlab.ui.container.Panel
        StopButton                  matlab.ui.control.Button
        SelectDropDown              matlab.ui.control.DropDown
        SelectDropDownLabel         matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             s = serialport('COM3',9600);
             s.BaudRate = 19200;
            
            
            plotGrid="on";
            v_s=5.0;
            count_reference=330;
            density_water=997;
            gravity=9.81;
            count=1;
            delay=0.2;
            value=app.SelectDropDown.Value;
            keepRunning=true;
            
            while keepRunning
                    dat=readline(s);
                    data=sscanf(dat, '%f V,%f counts,%f ms');
                    voltage = data(1);
                    count_number = data(2);
                    time_interval = data(3);
                    pressure=1/0.0018*(voltage/v_s-0.04);
                    head=pressure/density_water/gravity*1000;
                    flow_rate=count_number/count_reference*1000/time_interval;
                    hydraulic_power=pressure*flow_rate;
                    app.PressurekPaGauge.Value=pressure;
                    app.FlowRateLsGauge.Value=flow_rate;
                    app.HeadmGauge.Value=head;
                    app.HydraulicPowerkWGauge.Value=hydraulic_power;
                    
                    switch value
                        case 'Pressure vs. Time'
                            ax=app.UIAxes;
                            title(ax, 'Pressure vs.Time','FontSize',18);
                            xlabel(ax, 'Time, s','FontSize',15);
                            ylabel(ax, 'Pressure, kPa','FontSize',15);
                            grid(ax, plotGrid);
                            
                            time(count)=count/2;
                            ppressure(count)=pressure;
                            count=count+1;
                            plot(ax, time, ppressure, '-r')
                            
                            
                        case 'Flow Rate vs. Time'
                             %Set up Plot
                              ax = app.UIAxes;
                              title(ax, 'Flow rate vs.Time','FontSize',18);
                              xlabel(ax, 'Time, s','FontSize',15);
                              ylabel(ax, 'Flow rate, L/s','FontSize',15);
                              grid(ax, plotGrid);
                              
                              time(count)=count/2;
                              fflow_rate(count)=flow_rate;
                              count=count+1;
                              plot(ax, time, fflow_rate, '-r')
                              case 'Head vs. Flow Rate'
                             %Set up Plot
                              ax = app.UIAxes;
                              title(ax, 'Head vs.Flow rate','FontSize',18);
                              xlabel(ax, 'Flow rate, L/s','FontSize',15);
                              ylabel(ax, 'Head, m','FontSize',15);
                              grid(ax, plotGrid);
                              
                              fflow_rate(count)=flow_rate;
                              hhead(count)=head;
                              count=count+1;
                              plot(ax, fflow_rate, hhead, '-r');
                               
                              
                        case 'Hydraulic Power vs. Flow Rate'
                             %Set up Plot
                             ax = app.UIAxes;
                             title(ax, 'Hydraulic power vs.Flow rate','FontSize',18);
                             xlabel(ax, 'Flow rate, L/s','FontSize',15);
                             ylabel(ax, 'Hydraulic power, kW','FontSize',15);
                             grid(ax, plotGrid);
                             fflow_rate(count)=flow_rate;
                             hhydraulic_power(count)=hydraulic_power;
                             count=count+1;
                             plot(ax, fflow_rate, hhydraulic_power, '-r');
                               
                             
                    end
                    
                  pause(delay);
                end 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            pause;
            keepRunning=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectLiveDataPanel = uipanel(app.UIFigure);
            app.SelectLiveDataPanel.Title = 'Select ''Live'' Data';
            app.StartButton = uibutton(app.SelectLiveDataPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectDropDownLabel = uilabel(app.SelectLiveDataPanel);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.SelectLiveDataPanel);
            app.SelectDropDown.Items = {'Pressure vs. Time', 'Flow Rate vs. Time', 'Head vs. Flow Rate', 'Hydraulic Power vs. Flow Rate'};
            app.SelectDropDown.Value = 'Pressure vs. Time';
            app.StopButton = uibutton(app.SelectLiveDataPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot Panel';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GaugePanel = uipanel(app.UIFigure);
            app.GaugePanel.Title = 'Gauge Panel';
            app.PressurekPaGaugeLabel = uilabel(app.GaugePanel);
            app.PressurekPaGaugeLabel.Text = 'Pressure, kPa';
            app.PressurekPaGauge = uigauge(app.GaugePanel, 'circular');
            app.PressurekPaGauge.Limits = [0 30];
            app.FlowRateLsGaugeLabel = uilabel(app.GaugePanel);
            app.FlowRateLsGaugeLabel.Text = 'Flow Rate, L/s';
            app.FlowRateLsGauge = uigauge(app.GaugePanel, 'circular');
            app.FlowRateLsGauge.Limits = [0 0.3];
            app.HeadmGaugeLabel = uilabel(app.GaugePanel);
            app.HeadmGaugeLabel.Text = 'Head, m';
            app.HeadmGauge = uigauge(app.GaugePanel, 'circular');
            app.HeadmGauge.Limits = [0 3];
            app.HydraulicPowerkWGaugeLabel = uilabel(app.GaugePanel);
            app.HydraulicPowerkWGaugeLabel.Text = 'Hydraulic Power, kW';
            app.HydraulicPowerkWGauge = uigauge(app.GaugePanel, 'circular');
            app.HydraulicPowerkWGauge.Limits = [0 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_Pump_Monitorv2_4gauges_live

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end